classdef appPlotLattices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadAtomicSitescifButton  matlab.ui.control.Button
        ExporttoworkspaceButton   matlab.ui.control.Button
        kunitsDropDown            matlab.ui.control.DropDown
        kunitsDropDownLabel       matlab.ui.control.Label
        gammaEditField            matlab.ui.control.NumericEditField
        gammaEditFieldLabel       matlab.ui.control.Label
        betaEditField             matlab.ui.control.NumericEditField
        betaEditFieldLabel        matlab.ui.control.Label
        alphaEditField            matlab.ui.control.NumericEditField
        alphaEditFieldLabel       matlab.ui.control.Label
        cEditField                matlab.ui.control.NumericEditField
        cEditFieldLabel           matlab.ui.control.Label
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        BravaisDropDown           matlab.ui.control.DropDown
        BravaisDropDownLabel      matlab.ui.control.Label
        RecipAxes                 matlab.ui.control.UIAxes
        RealAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        latticeParams % lattice parameters
        latticeAngles
        latticeChoice
        BravaisList % List of the 14 Bravais lattices
        reciprUnit % Units used for reciprocal space

        latticeData
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

s = uistyle(Interpreter='tex');
addStyle(app.kunitsDropDown,s);


        end

        % Value changed function: BravaisDropDown, aEditField, 
        % ...and 6 other components
        function updatePlot(app, event)
            % Get current lattice structure, parameters and angle
            app.latticeChoice = app.BravaisDropDown.Value;

            app.latticeParams = [app.aEditField.Value,...
                app.bEditField.Value,...
                app.cEditField.Value];

            app.latticeAngles = [app.alphaEditField.Value,...
                app.betaEditField.Value,...
                app.gammaEditField.Value];

app.latticeData = createBravaisLattice(app.latticeChoice, ...
    app.latticeParams,app.latticeAngles);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RealAxes = uiaxes(app.UIFigure);
            title(app.RealAxes, 'Title')
            xlabel(app.RealAxes, 'X')
            ylabel(app.RealAxes, 'Y')
            zlabel(app.RealAxes, 'Z')
            app.RecipAxes = uiaxes(app.UIFigure);
            title(app.RecipAxes, 'Title')
            xlabel(app.RecipAxes, 'k_x')
            ylabel(app.RecipAxes, 'k_y')
            zlabel(app.RecipAxes, 'k_z')
            app.BravaisDropDownLabel = uilabel(app.UIFigure);
            app.BravaisDropDownLabel.Text = 'Bravais';
            app.BravaisDropDown = uidropdown(app.UIFigure);
            app.BravaisDropDown.Items = {'Cubic', 'BCC', 'FCC', 'Tetragonal Simple', 'Body Centered tetragonal', 'Orthorhombic', 'Body Centered Ortho.', 'Face Centered Ortho.', 'Side Centered Ortho.', 'Hexagonal', 'Rhombohedral', 'Monoclinic', 'C centered Mono.', 'Triclinic'};
            app.BravaisDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.BravaisDropDown.Value = 'Body Centered Ortho.';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Limits = [0 Inf];
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Limits = [0 Inf];
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.bEditField.Value = 1;
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Limits = [0 Inf];
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.cEditField.Value = 1;
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Interpreter = 'tex';
            app.alphaEditFieldLabel.Text = '\alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField.Limits = [0 180];
            app.alphaEditField.ValueDisplayFormat = '%11.4gº';
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.alphaEditField.Value = 90;
            app.betaEditFieldLabel = uilabel(app.UIFigure);
            app.betaEditFieldLabel.Interpreter = 'tex';
            app.betaEditFieldLabel.Text = '\beta';
            app.betaEditField = uieditfield(app.UIFigure, 'numeric');
            app.betaEditField.Limits = [0 180];
            app.betaEditField.ValueDisplayFormat = '%11.4gº';
            app.betaEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.betaEditField.Value = 90;
            app.gammaEditFieldLabel = uilabel(app.UIFigure);
            app.gammaEditFieldLabel.Interpreter = 'tex';
            app.gammaEditFieldLabel.Text = '\gamma';
            app.gammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.gammaEditField.Limits = [0 180];
            app.gammaEditField.ValueDisplayFormat = '%11.4gº';
            app.gammaEditField.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.gammaEditField.Value = 90;
            app.kunitsDropDownLabel = uilabel(app.UIFigure);
            app.kunitsDropDownLabel.Text = 'k units';
            app.kunitsDropDown = uidropdown(app.UIFigure);
            app.kunitsDropDown.Items = {'1/\lambda', '2\pi/\lambda', '2\pi/{\ita_i}'};
            app.kunitsDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.kunitsDropDown.Value = '1/\lambda';
            app.ExporttoworkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoworkspaceButton.Enable = 'off';
            app.ExporttoworkspaceButton.Text = 'Export to workspace';
            app.LoadAtomicSitescifButton = uibutton(app.UIFigure, 'push');
            app.LoadAtomicSitescifButton.Enable = 'off';
            app.LoadAtomicSitescifButton.Text = 'Load Atomic Sites/cif?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPlotLattices

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end