classdef appPlotLattices_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        RealTab               matlab.ui.container.Tab
        RealAxes              matlab.ui.control.UIAxes
        ReciprocalTab         matlab.ui.container.Tab
        RecipAxes             matlab.ui.control.UIAxes
        Tab                   matlab.ui.container.Tab
        Panel_2               matlab.ui.container.Panel
        RecipCellDropDown     matlab.ui.control.DropDown
        CellDropDown_2Label   matlab.ui.control.Label
        kunitsDropDown        matlab.ui.control.DropDown
        kunitsDropDownLabel   matlab.ui.control.Label
        RecipUndock           matlab.ui.control.Image
        VectorsCheckBox       matlab.ui.control.CheckBox
        RealCellDropDown      matlab.ui.control.DropDown
        CellDropDownLabel     matlab.ui.control.Label
        unitsDropDown         matlab.ui.control.DropDown
        unitsDropDownLabel    matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        ToWorkspaceLabel      matlab.ui.control.Label
        toWorkspaceButton     matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        NewButton             matlab.ui.control.Button
        gammaEditField        matlab.ui.control.NumericEditField
        gammaEditFieldLabel   matlab.ui.control.Label
        betaEditField         matlab.ui.control.NumericEditField
        betaEditFieldLabel    matlab.ui.control.Label
        alphaEditField        matlab.ui.control.NumericEditField
        alphaEditFieldLabel   matlab.ui.control.Label
        cEditField            matlab.ui.control.NumericEditField
        cEditFieldLabel       matlab.ui.control.Label
        bEditField            matlab.ui.control.NumericEditField
        bEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.NumericEditField
        aEditFieldLabel       matlab.ui.control.Label
        BravaisDropDownLabel  matlab.ui.control.Label
        BravaisDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        latticeParams % lattice parameters
        latticeAngles % angles between lattice vectors
        latticeChoice
        BravaisList % List of the 14 Bravais lattices
        reciprUnit % Units used for reciprocal space

        latticeData
        crystalData
    end
    
    methods (Access = private)
        
        function enableParameters(app,bravais) % Use to re-enable parameters
            % The name tells type of system and cell, so I can classify
            % which parameters are relevant
            % 1st letter is system, 2nd the type
            target = [app.bEditField, app.cEditField,...
                app.alphaEditField,app.betaEditField,app.gammaEditField];
            set(target,'Enable','off');
            switch bravais(1)
                case 'c' % cubic
                    idEnable = [];
                    [target(1:2).Value] = deal(app.aEditField.Value);
                    set(target(3:end),'Value',90);

                case 't' % tetragonal
                    idEnable = 2;
                    target(1).Value = app.aEditField.Value;
                     set(target(3:end),'Value',90);

                case 'o' % orthogonal
                    idEnable = [1 2];
                     set(target(3:end),'Value',90);
                case 'h' % hexagonal (primitive)
                    if bravais(2)=='P'
                        idEnable = [2];
                        target(1).Value = app.aEditField.Value;
                        app.alphaEditField.Value = 120;
                        set(target(4:end),'Value',90);

                    elseif bravais(2)=='R' % rhombohedral
                        idEnable = [3];
                        [target(1:2).Value] = deal(app.aEditField.Value);
                        [target(4:end).Value] = deal(app.alphaEditField.Value);
                    end

                case 'm' % monoclinic
                    idEnable = 1:3;
                    set(target(4:end),'Value',90);
                case 'a' % triclinic
                    idEnable = 1:length(target);
            end
            set(target(idEnable),'Enable','on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Make dropdowns with symbols readable in Latex
            s = uistyle(Interpreter='tex');
            addStyle(app.kunitsDropDown,s);
            addStyle(app.unitsDropDown,s);
        end

        % Callback function: not associated with a component
        function updatePlots(app, event)
            % Get current lattice structure, parameters and angle
            app.latticeChoice = app.BravaisDropDown.Value;

            app.latticeParams = [app.aEditField.Value,...
                app.bEditField.Value,...
                app.cEditField.Value];

            app.latticeAngles = [app.alphaEditField.Value,...
                app.betaEditField.Value,...
                app.gammaEditField.Value];

            app.latticeData = createBravaisLattice(app.latticeChoice, ...
                app.latticeParams,app.latticeAngles);

            % Call function to plot lattices
            plotLattices(app.latticeData,app.RealAxes,app.RecipAxes);


        end

        % Value changed function: BravaisDropDown
        function BravaisDropDownValueChanged(app, event)
            bravaisValue = app.BravaisDropDown.Value;
            % Enable lattice properties according to selected Lattice
            enableParameters(app,bravaisValue);

            app.updatePlots();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,location] = uigetfile('*.cif');
            if ~isequal(file,0)
                crystal = cif(fullfile(location,file));

                % Create data for crystal.
                app.crystalData = crystal;
                app.latticeParams = crystal.LatticeInfo;
                app.latticeAngles = crystal.angles;
                app.latticeChoice = crystal.bravais;
            end
        end

        % Button pushed function: toWorkspaceButton
        function toWorkspaceButtonPushed(app, event)
            % Assign to main workspace the important variables
            assignin('base','crystal',app.crystalData)
            assignin('base','crystalLat',app.latticeData)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.BravaisDropDown = uidropdown(app.Panel);
            app.BravaisDropDown.Items = {'Cubic', 'BCC', 'FCC', 'Tetragonal Simple', 'Body Centered tetragonal', 'Orthorhombic', 'Body Centered Ortho.', 'Face Centered Ortho.', 'Side Centered Ortho.', 'Hexagonal', 'Rhombohedral', 'Monoclinic', 'C centered Mono.', 'Triclinic'};
            app.BravaisDropDown.ItemsData = {'cP', 'cI', 'cF', 'tP', 'tI', 'oP', 'oI', 'oF', 'oS', 'hP', 'hR', 'mP', 'mS', 'aP'};
            app.BravaisDropDown.ValueChangedFcn = createCallbackFcn(app, @BravaisDropDownValueChanged, true);
            app.BravaisDropDown.Value = 'cP';
            app.BravaisDropDownLabel = uilabel(app.Panel);
            app.BravaisDropDownLabel.Text = 'Bravais';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel, 'numeric');
            app.aEditField.Limits = [0 Inf];
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel, 'numeric');
            app.bEditField.Limits = [0 Inf];
            app.bEditField.Enable = 'off';
            app.bEditField.Value = 1;
            app.cEditFieldLabel = uilabel(app.Panel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel, 'numeric');
            app.cEditField.Limits = [0 Inf];
            app.cEditField.Enable = 'off';
            app.cEditField.Value = 1;
            app.alphaEditFieldLabel = uilabel(app.Panel);
            app.alphaEditFieldLabel.Interpreter = 'tex';
            app.alphaEditFieldLabel.Text = '\alpha';
            app.alphaEditField = uieditfield(app.Panel, 'numeric');
            app.alphaEditField.Limits = [0 180];
            app.alphaEditField.ValueDisplayFormat = '%11.4gº';
            app.alphaEditField.Enable = 'off';
            app.alphaEditField.Value = 90;
            app.betaEditFieldLabel = uilabel(app.Panel);
            app.betaEditFieldLabel.Interpreter = 'tex';
            app.betaEditFieldLabel.Text = '\beta';
            app.betaEditField = uieditfield(app.Panel, 'numeric');
            app.betaEditField.Limits = [0 180];
            app.betaEditField.ValueDisplayFormat = '%11.4gº';
            app.betaEditField.Enable = 'off';
            app.betaEditField.Value = 90;
            app.gammaEditFieldLabel = uilabel(app.Panel);
            app.gammaEditFieldLabel.Interpreter = 'tex';
            app.gammaEditFieldLabel.Text = '\gamma';
            app.gammaEditField = uieditfield(app.Panel, 'numeric');
            app.gammaEditField.Limits = [0 180];
            app.gammaEditField.ValueDisplayFormat = '%11.4gº';
            app.gammaEditField.Enable = 'off';
            app.gammaEditField.Value = 90;
            app.NewButton = uibutton(app.Panel, 'push');
            app.NewButton.Enable = 'off';
            app.NewButton.Text = 'New';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.toWorkspaceButton = uibutton(app.Panel, 'push');
            app.toWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @toWorkspaceButtonPushed, true);
            app.toWorkspaceButton.Text = '';
            app.ToWorkspaceLabel = uilabel(app.Panel);
            app.ToWorkspaceLabel.Text = 'To Workspace';
            app.Panel_2 = uipanel(app.GridLayout);
            app.unitsDropDownLabel = uilabel(app.Panel_2);
            app.unitsDropDownLabel.Text = 'units';
            app.unitsDropDown = uidropdown(app.Panel_2);
            app.unitsDropDown.Items = {'L', 'L/{\ita_i}'};
            app.unitsDropDown.Value = 'L';
            app.CellDropDownLabel = uilabel(app.Panel_2);
            app.CellDropDownLabel.Text = 'Cell';
            app.RealCellDropDown = uidropdown(app.Panel_2);
            app.RealCellDropDown.Items = {'Conventional', 'Primitive', 'Wigner-Seitz'};
            app.RealCellDropDown.Value = 'Conventional';
            app.VectorsCheckBox = uicheckbox(app.Panel_2);
            app.VectorsCheckBox.Enable = 'off';
            app.VectorsCheckBox.Text = 'Vectors';
            app.RecipUndock = uiimage(app.Panel_2);
            app.kunitsDropDownLabel = uilabel(app.Panel_2);
            app.kunitsDropDownLabel.Text = 'k units';
            app.kunitsDropDown = uidropdown(app.Panel_2);
            app.kunitsDropDown.Items = {'1/\lambda', '2\pi/\lambda', '2\pi/{\ita_i}'};
            app.kunitsDropDown.Value = '1/\lambda';
            app.CellDropDown_2Label = uilabel(app.Panel_2);
            app.CellDropDown_2Label.Text = 'Cell';
            app.RecipCellDropDown = uidropdown(app.Panel_2);
            app.RecipCellDropDown.Items = {'Conventional', 'Primitive', 'Brillouin'};
            app.RecipCellDropDown.Value = 'Brillouin';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RealTab = uitab(app.TabGroup);
            app.RealTab.Title = 'Real';
            app.RealAxes = uiaxes(app.RealTab);
            title(app.RealAxes, 'Title')
            xlabel(app.RealAxes, 'X')
            ylabel(app.RealAxes, 'Y')
            zlabel(app.RealAxes, 'Z')
            app.ReciprocalTab = uitab(app.TabGroup);
            app.ReciprocalTab.Title = 'Reciprocal';
            app.RecipAxes = uiaxes(app.ReciprocalTab);
            title(app.RecipAxes, 'Title')
            xlabel(app.RecipAxes, 'k_x')
            ylabel(app.RecipAxes, 'k_y')
            zlabel(app.RecipAxes, 'k_z')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPlotLattices_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end